classdef NanosensorImaging < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NanosensorImagingAppUIFigure  matlab.ui.Figure
        LoadTIFStackButton            matlab.ui.control.Button
        BatchProcessButton            matlab.ui.control.Button
        CurrentFileTextAreaLabel      matlab.ui.control.Label
        CurrentFileTextArea           matlab.ui.control.TextArea
        StimulusFrameNumberEditFieldLabel  matlab.ui.control.Label
        StimulusFrameNumberEditField  matlab.ui.control.EditField
        ProcessTimeSeriesButton       matlab.ui.control.Button
        GridSizepixelsEditFieldLabel  matlab.ui.control.Label
        GridSizepixelsEditField       matlab.ui.control.EditField
        FrameRateHzEditFieldLabel     matlab.ui.control.Label
        FrameRateHzEditField          matlab.ui.control.EditField
        DetectSpikesandFitButton      matlab.ui.control.Button
        PixelsmEditFieldLabel         matlab.ui.control.Label
        PixelsmEditField              matlab.ui.control.NumericEditField
        SaveResultsPanel              matlab.ui.container.Panel
        ToFileButton                  matlab.ui.control.Button
        ToMatlabWorkspaceButton       matlab.ui.control.Button
        dFFFrameEditFieldLabel        matlab.ui.control.Label
        dFFFrameEditField             matlab.ui.control.NumericEditField
        dFFFrameCheckBox              matlab.ui.control.CheckBox
        dFFClipLowEditFieldLabel      matlab.ui.control.Label
        dFFClipLowEditField           matlab.ui.control.NumericEditField
        dFFClipHighEditFieldLabel     matlab.ui.control.Label
        dFFClipHighEditField          matlab.ui.control.NumericEditField
        ResultsSummaryPanel           matlab.ui.container.Panel
        UISummaryTable                matlab.ui.control.Table
        VersionNumberLabel            matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        imageStack; % Stores the Tif image stack
        versionNumber = '[1.3.4](2020-05-01)'; %Version 1.3.4
    end
    
    properties (Access = public)
        results=struct('roiData',struct(),'roiMask',[],'imageStackInfo',struct(),...
            'fitData',struct()); % Description
        imageStackInfo=struct('pathName',[],'fileName',[],...
            'numFrames',[],'height',[],'width',[],...
            'stimFrame',[],'gridSize',[]); % Description
        roiMask = [];
    end
    
    properties (Access = public)
    end
    
    properties (Access = public)
    end
    
    methods (Access = private)
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.VersionNumberLabel.Text = strcat('Version: ',app.versionNumber);
            app.results.versionNumber = app.versionNumber;
        end

        % Button pushed function: LoadTIFStackButton
        function LoadTIFStackButtonPushed(app, event)
            loadTifStack(app);
            app.CurrentFileTextArea.Value = app.imageStackInfo.fileName;
            
        end

        % Button pushed function: BatchProcessButton
        function BatchProcessButtonPushed(app, event)
            batchProcessTifs(app);
        end

        % Button pushed function: ProcessTimeSeriesButton
        function ProcessTimeSeriesButtonPushed(app, event)
            app.imageStackInfo.stimFrame = str2double(app.StimulusFrameNumberEditField.Value);
            app.imageStackInfo.gridSize = str2double(app.GridSizepixelsEditField.Value);
            app.imageStackInfo.frameRate = str2double(app.FrameRateHzEditField.Value);
            app.roiMask = generateGrid(app);
            app.results.roiData = processROI(app);
            app.results.roiMask = app.roiMask;
            app.results.imageStackInfo = app.imageStackInfo;
            app.results.imageStackInfo.firstFrame = mean(app.imageStack(:,:,1:5),3);
        end

        % Button pushed function: DetectSpikesandFitButton
        function DetectSpikesandFitButtonPushed(app, event)
            stimFrame = app.imageStackInfo.stimFrame;
            frameRate = app.imageStackInfo.frameRate;
            app.results.fitData = struct();
            f = app.NanosensorImagingAppUIFigure;
            d = uiprogressdlg(f,'Title','Identifying Spikes and Curve Fitting',...
                'Message','Please Wait','Cancelable','on');
            for i = 1:length(app.results.roiData)
                if d.CancelRequested
                    break
                end
                d.Value = i./length(app.results.roiData);
                traceData = app.results.roiData(i).dFdetrend;
                [app.results.fitData(i).fitResults,...
                    app.results.fitData(i).rootResNorm,...
                    app.results.fitData(i).fitPlot,...
                    app.results.fitData(i).spikeLocs,...
                    app.results.fitData(i).significance]...
                    = fitExponentialFunction(traceData,stimFrame,frameRate);
            end
            summaryData = generateSummary(app.results);
            app.UISummaryTable.Data = summaryData;
            generateReport(app);
        end

        % Button pushed function: ToMatlabWorkspaceButton
        function ToMatlabWorkspaceButtonPushed(app, event)
            assignin('base', 'results', app.results);
        end

        % Value changed function: StimulusFrameNumberEditField
        function StimulusFrameNumberEditFieldValueChanged(app, event)
            value = app.StimulusFrameNumberEditField.Value;
        end

        % Button pushed function: ToFileButton
        function ToFileButtonPushed(app, event)
            exportResults(app);
        end

        % Value changed function: dFFFrameCheckBox
        function dFFFrameCheckBoxValueChanged(app, event)
            value = app.dFFFrameCheckBox.Value;
            app.dFFFrameEditField.Enable = value;
            app.dFFFrameEditFieldLabel.Enable = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NanosensorImagingAppUIFigure = uifigure('Visible', 'off');
            app.NanosensorImagingAppUIFigure.Name = 'Nanosensor-Imaging-App';
            app.LoadTIFStackButton = uibutton(app.NanosensorImagingAppUIFigure, 'push');
            app.LoadTIFStackButton.ButtonPushedFcn = createCallbackFcn(app, @LoadTIFStackButtonPushed, true);
            app.LoadTIFStackButton.Text = 'Load TIF Stack';
            app.BatchProcessButton = uibutton(app.NanosensorImagingAppUIFigure, 'push');
            app.BatchProcessButton.ButtonPushedFcn = createCallbackFcn(app, @BatchProcessButtonPushed, true);
            app.BatchProcessButton.Text = 'Batch Process';
            app.CurrentFileTextAreaLabel = uilabel(app.NanosensorImagingAppUIFigure);
            app.CurrentFileTextAreaLabel.Text = 'Current File:';
            app.CurrentFileTextArea = uitextarea(app.NanosensorImagingAppUIFigure);
            app.StimulusFrameNumberEditFieldLabel = uilabel(app.NanosensorImagingAppUIFigure);
            app.StimulusFrameNumberEditFieldLabel.Text = 'Stimulus Frame Number';
            app.StimulusFrameNumberEditField = uieditfield(app.NanosensorImagingAppUIFigure, 'text');
            app.StimulusFrameNumberEditField.ValueChangedFcn = createCallbackFcn(app, @StimulusFrameNumberEditFieldValueChanged, true);
            app.StimulusFrameNumberEditField.Value = '200';
            app.ProcessTimeSeriesButton = uibutton(app.NanosensorImagingAppUIFigure, 'push');
            app.ProcessTimeSeriesButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessTimeSeriesButtonPushed, true);
            app.ProcessTimeSeriesButton.Text = 'Process Time Series';
            app.GridSizepixelsEditFieldLabel = uilabel(app.NanosensorImagingAppUIFigure);
            app.GridSizepixelsEditFieldLabel.Text = 'Grid Size (pixels)';
            app.GridSizepixelsEditField = uieditfield(app.NanosensorImagingAppUIFigure, 'text');
            app.GridSizepixelsEditField.Value = '25';
            app.FrameRateHzEditFieldLabel = uilabel(app.NanosensorImagingAppUIFigure);
            app.FrameRateHzEditFieldLabel.Text = 'Frame Rate (Hz)';
            app.FrameRateHzEditField = uieditfield(app.NanosensorImagingAppUIFigure, 'text');
            app.FrameRateHzEditField.Value = '8.33';
            app.DetectSpikesandFitButton = uibutton(app.NanosensorImagingAppUIFigure, 'push');
            app.DetectSpikesandFitButton.ButtonPushedFcn = createCallbackFcn(app, @DetectSpikesandFitButtonPushed, true);
            app.DetectSpikesandFitButton.Text = 'Detect Spikes and Fit';
            app.PixelsmEditFieldLabel = uilabel(app.NanosensorImagingAppUIFigure);
            app.PixelsmEditFieldLabel.Text = 'Pixels / µm';
            app.PixelsmEditField = uieditfield(app.NanosensorImagingAppUIFigure, 'numeric');
            app.PixelsmEditField.Value = 3.67;
            app.SaveResultsPanel = uipanel(app.NanosensorImagingAppUIFigure);
            app.SaveResultsPanel.Title = 'Save Results:';
            app.ToFileButton = uibutton(app.SaveResultsPanel, 'push');
            app.ToFileButton.ButtonPushedFcn = createCallbackFcn(app, @ToFileButtonPushed, true);
            app.ToFileButton.Text = 'To File';
            app.ToMatlabWorkspaceButton = uibutton(app.SaveResultsPanel, 'push');
            app.ToMatlabWorkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @ToMatlabWorkspaceButtonPushed, true);
            app.ToMatlabWorkspaceButton.Text = 'To Matlab Workspace';
            app.dFFFrameEditFieldLabel = uilabel(app.SaveResultsPanel);
            app.dFFFrameEditFieldLabel.Enable = 'off';
            app.dFFFrameEditFieldLabel.Text = 'dF/F Frame';
            app.dFFFrameEditField = uieditfield(app.SaveResultsPanel, 'numeric');
            app.dFFFrameEditField.Enable = 'off';
            app.dFFFrameEditField.Value = 100;
            app.dFFFrameCheckBox = uicheckbox(app.SaveResultsPanel);
            app.dFFFrameCheckBox.ValueChangedFcn = createCallbackFcn(app, @dFFFrameCheckBoxValueChanged, true);
            app.dFFFrameCheckBox.Text = {''; ''};
            app.dFFClipLowEditFieldLabel = uilabel(app.SaveResultsPanel);
            app.dFFClipLowEditFieldLabel.Text = 'dF/F Clip % Low:';
            app.dFFClipLowEditField = uieditfield(app.SaveResultsPanel, 'numeric');
            app.dFFClipLowEditField.Limits = [0 100];
            app.dFFClipLowEditField.Value = 5;
            app.dFFClipHighEditFieldLabel = uilabel(app.SaveResultsPanel);
            app.dFFClipHighEditFieldLabel.Text = 'dF/F Clip % High:';
            app.dFFClipHighEditField = uieditfield(app.SaveResultsPanel, 'numeric');
            app.dFFClipHighEditField.Limits = [0 100];
            app.dFFClipHighEditField.Value = 90;
            app.ResultsSummaryPanel = uipanel(app.NanosensorImagingAppUIFigure);
            app.ResultsSummaryPanel.Title = 'Results Summary';
            app.UISummaryTable = uitable(app.NanosensorImagingAppUIFigure);
            app.UISummaryTable.ColumnName = {'ROI Total'; 'Signif ROIs'; '% Sig ROIs'; 'Mean τ On'; 'Mean τ Off'; 'Mean AUC'; 'Mean Peak dF/F'};
            app.UISummaryTable.RowName = {};
            app.VersionNumberLabel = uilabel(app.NanosensorImagingAppUIFigure);
            app.VersionNumberLabel.Text = 'VersionNumber';
            app.UIAxes = uiaxes(app.NanosensorImagingAppUIFigure);
            title(app.UIAxes, 'Frame 1 (µm)')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            % Show the figure after all components are created
            app.NanosensorImagingAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NanosensorImaging

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NanosensorImagingAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NanosensorImagingAppUIFigure)
        end
    end
end